classdef TuxNet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TuxTab                          matlab.ui.container.Tab
        Tux_InfoTextArea                matlab.ui.control.TextArea
        Tux_RunPanel_TuxOP              matlab.ui.container.Panel
        Tux_RunButton_TuxOP             matlab.ui.control.Button
        Tux_StatusField_TuxOP           matlab.ui.control.TextArea
        Tux_CleaningPanel               matlab.ui.container.Panel
        Tux_CleaningInputsFolderButton  matlab.ui.control.Button
        InputsFolderLabel               matlab.ui.control.Label
        Tux_CleaningInputsFolderEditField  matlab.ui.control.EditField
        WindowSizeLabel                 matlab.ui.control.Label
        Tux_CleaningWindowSizeEditField  matlab.ui.control.NumericEditField
        QualityThresholdLabel           matlab.ui.control.Label
        Tux_CleaningQualityThresholdEditField  matlab.ui.control.NumericEditField
        MinSequenceLengthLabel          matlab.ui.control.Label
        Tux_CleaningMinSeqLengthEditField  matlab.ui.control.NumericEditField
        Tux_TophatPanel                 matlab.ui.container.Panel
        ThreadsLabel                    matlab.ui.control.Label
        Tux_TophatThreadsEditField      matlab.ui.control.NumericEditField
        Tux_TophatGTFGFFFileButton      matlab.ui.control.Button
        ReferenceGenomeLabel            matlab.ui.control.Label
        Tux_TophatGTFGFFFileEditField   matlab.ui.control.EditField
        LibraryTypeDropDownLabel        matlab.ui.control.Label
        Tux_TophatLibraryTypeDropDown   matlab.ui.control.DropDown
        Tux_CufflinksFASTAFileButton    matlab.ui.control.Button
        FASTAFileLabel                  matlab.ui.control.Label
        Tux_CufflinksFASTAFileEditField  matlab.ui.control.EditField
        Tux_TuxOPPanel                  matlab.ui.container.Panel
        FCThresholdLabel                matlab.ui.control.Label
        Tux_CleaningQualityThresholdEditField_1  matlab.ui.control.NumericEditField
        FDRThresholdLabel               matlab.ui.control.Label
        Tux_CleaningQualityThresholdEditField_2  matlab.ui.control.NumericEditField
        TFFileOptionalLabel_3           matlab.ui.control.Label
        Tux_TFFileEditField             matlab.ui.control.EditField
        GeneNameFileOptionalLabel_3     matlab.ui.control.Label
        Tux_SymbolFileEditField         matlab.ui.control.EditField
        Tux_TFFileButton                matlab.ui.control.Button
        Tux_SymbolFileButton            matlab.ui.control.Button
        SampleGroup1EditFieldLabel      matlab.ui.control.Label
        Tux_SampleGroup1EditField       matlab.ui.control.EditField
        SampleGroup2EditFieldLabel      matlab.ui.control.Label
        Tux_SampleGroup2EditField       matlab.ui.control.EditField
        Tux_CommandLinePanel            matlab.ui.container.Panel
        Tux_CommandLineTextArea         matlab.ui.control.TextArea
        Tux_RunPanel_Tuxedo             matlab.ui.container.Panel
        Tux_RunButton_Tuxedo            matlab.ui.control.Button
        Tux_StatusField_Tuxedo          matlab.ui.control.TextArea
        Tux_InstructionsPanel           matlab.ui.container.Panel
        Tux_InstructionsTextArea        matlab.ui.control.TextArea
        GENISTTab                       matlab.ui.container.Tab
        GENIST_InfoTextArea             matlab.ui.control.TextArea
        GENIST_RunPanel                 matlab.ui.container.Panel
        GENIST_RunButton                matlab.ui.control.Button
        GENIST_StatusField              matlab.ui.control.TextArea
        GENIST_InputsInfoPanel          matlab.ui.container.Panel
        GENIST_InfoListBox              matlab.ui.control.ListBox
        GENIST_InputsInfoTextArea       matlab.ui.control.TextArea
        GENIST_OptionalInputsPanel      matlab.ui.container.Panel
        TimeLapseDropDownLabel          matlab.ui.control.Label
        GENIST_TimeLapseDropDown        matlab.ui.control.DropDown
        RegFoldChangeThresholdEditFieldLabel  matlab.ui.control.Label
        GENIST_RegFoldChangeThresholdEditField  matlab.ui.control.NumericEditField
        RegTimePercentEditFieldLabel    matlab.ui.control.Label
        GENIST_RegTimePercentEditField  matlab.ui.control.NumericEditField
        DiscretizationLevelsLabel       matlab.ui.control.Label
        GENIST_NLevelsEditField         matlab.ui.control.NumericEditField
        RegBottomPercentageEditFieldLabel  matlab.ui.control.Label
        GENIST_RegBottomPercentageEditField  matlab.ui.control.NumericEditField
        GENIST_RequiredInputsPanel      matlab.ui.container.Panel
        GENIST_GenesFileButton          matlab.ui.control.Button
        GENIST_TimeCourseFileButton     matlab.ui.control.Button
        GENIST_ClusteringFileButton     matlab.ui.control.Button
        GenesFileEditFieldLabel         matlab.ui.control.Label
        GENIST_GenesFileEditField       matlab.ui.control.EditField
        TimeCourseFileEditFieldLabel    matlab.ui.control.Label
        GENIST_TimeCourseFileEditField  matlab.ui.control.EditField
        ClusteringFileOptionalLabel_2   matlab.ui.control.Label
        GENIST_ClusteringFileEditField  matlab.ui.control.EditField
        OutputFileNameLabel             matlab.ui.control.Label
        GENIST_OutputFileEditField      matlab.ui.control.EditField
        GENIST_TFFileButton             matlab.ui.control.Button
        GENIST_SymbolFileButton         matlab.ui.control.Button
        TFFileOptionalLabel_2           matlab.ui.control.Label
        GENIST_TranscriptionFactorsFileEditField  matlab.ui.control.EditField
        GeneNameFileOptionalLabel_2     matlab.ui.control.Label
        GENIST_SymbolFileEditField      matlab.ui.control.EditField
        RTPSTARTab                      matlab.ui.container.Tab
        RTP_InfoTextArea                matlab.ui.control.TextArea
        RTP_ClusteringOptionsPanel      matlab.ui.container.Panel
        ClusteringSeedLabel             matlab.ui.control.Label
        RTP_ClusteringSeedEditField     matlab.ui.control.NumericEditField
        RTP_ConnectHubsLabel            matlab.ui.control.Label
        RTP_ConnectHubsCheckBox         matlab.ui.control.CheckBox
        RTP_ClusteringTypeDropDown      matlab.ui.control.DropDown
        RTP_ClusteringTypeLabel         matlab.ui.control.Label
        RTP_GeneralOptionsPanel         matlab.ui.container.Panel
        NumberofIterationsLabel         matlab.ui.control.Label
        RTP_NumitersEditField           matlab.ui.control.NumericEditField
        EdgeProportionLabel             matlab.ui.control.Label
        RTP_MaxpropEditField            matlab.ui.control.NumericEditField
        RTP_InputFilesPanel             matlab.ui.container.Panel
        ClusteringFileOptionalLabel     matlab.ui.control.Label
        RTP_ClusteringFileEditField     matlab.ui.control.EditField
        OutputsFolderLabel              matlab.ui.control.Label
        RTP_OutputsFolderEditField      matlab.ui.control.EditField
        GenesFileLabel                  matlab.ui.control.Label
        RTP_GeneFileEditField           matlab.ui.control.EditField
        GeneExpressionFileEditFieldLabel  matlab.ui.control.Label
        RTP_GeneExpressionFileEditField  matlab.ui.control.EditField
        TimeCourseFileOptionalLabel     matlab.ui.control.Label
        RTP_TimeCourseFileEditField     matlab.ui.control.EditField
        ResultsFileNameEditFieldLabel   matlab.ui.control.Label
        RTP_ResultsFileNameEditField    matlab.ui.control.EditField
        GeneNameFileOptionalLabel       matlab.ui.control.Label
        RTP_GeneSymbolsFileEditField    matlab.ui.control.EditField
        TFFileOptionalLabel             matlab.ui.control.Label
        RTP_TFFileEditField             matlab.ui.control.EditField
        RTP_InputsInfoPanel             matlab.ui.container.Panel
        RTP_InfoListBox                 matlab.ui.control.ListBox
        RTP_InputsInfoTextArea          matlab.ui.control.TextArea
        RTP_RunPanel                    matlab.ui.container.Panel
        RTP_RunButton                   matlab.ui.control.Button
        RTP_StatusField                 matlab.ui.control.TextArea
        RTP_OutputsFolderButton         matlab.ui.control.Button
        RTP_GeneFileButton              matlab.ui.control.Button
        RTP_GeneExpressionFileButton    matlab.ui.control.Button
        RTP_GeneSymbolsFileButton       matlab.ui.control.Button
        RTP_TFFileButton                matlab.ui.control.Button
        RTP_ClusteringFileButton        matlab.ui.control.Button
        RTP_TimeCourseFileButton        matlab.ui.control.Button
    end


    methods (Access = private)

        % Value changed function: GENIST_InfoListBox
        function GENIST_InfoListBoxValueChanged(app, event)
            listBoxValue = app.GENIST_InfoListBox.Value; % Change info text box based on user selection
            if(listBoxValue=="0")
                app.GENIST_InputsInfoTextArea.Value = ['Genes File must be an Excel file containing '...
                    'the list of genes to be included in the network as a column. The files containing '...
                    'genes of DEGs returned by TuxOP,  in the TUX tab, can be used as the Genes File.'];               
            elseif(listBoxValue=="1")
                app.GENIST_InputsInfoTextArea.Value = ['Time Course File must be an Excel file '...
                    'containing expression data of the genes across different time or developmental '...
                    'time points. Each row must correspond to one gene, and the gene names must be included '...
                    'in the first column. The file can contain '...
                    'any number of genes, and GENIST selects the genes specified in the genes file. '...
                    'The file gene_expression.xlsx returned by TuxOP, in the TUX tab, after processing '...
                    'a time-course dataset, can be used as the Time Course File.'];
            elseif(listBoxValue=="2")
                app.GENIST_InputsInfoTextArea.Value = ['Clustering Data File must be an Excel '... 
                    'file containing expression data of the genes across different '...
                    'spacial confinements, conditions, or treatments. Each row must correspond to 1 gene, '...
                    'and the gene names must be included in the first column. The '...
                    'file can contain any number of genes, and GENIST selects the '...
                    'genes specified in the genes file. The names of the genes in all '...
                    '.xlsx files must be consistent.'...
                    'The file gene_expression.xlsx returned by TuxOP, in the TUX tab, after processing '...
                    'the clustering dataset, can be used as the Clustering Data File.'];
            elseif(listBoxValue=="3")
                app.GENIST_InputsInfoTextArea.Value = ['Transcription Factors File must be an Excel file '...
                    'containing a list of transcription factors as in the first column of the file. '...
                    'If transcription factors file is provided, only the genes '...
                    '(from the genes file) that '...
                    'are also included in the transcription factors file will be considered '...
                    'as potential regulators in the network.'];
            elseif(listBoxValue=="4")
                app.GENIST_InputsInfoTextArea.Value = ['Gene Name File must be an Excel file '...
                    'containing a list of genes in the '...
                    'first column, and a corresponding name for each gene in the second '...
                    'column. When Gene Name File is provided, the genes in the network for '...
                    'which a name was provided are represented by their names.'];
            elseif(listBoxValue=="5")
                app.GENIST_InputsInfoTextArea.Value = ['Output File Name must be a name that will be used '...
                    'to save the output file. '...
                    'The results of GENIST will be stored in a .txt file with this name.'];
            elseif(listBoxValue=="6")
                app.GENIST_InputsInfoTextArea.Value = ['Time Lapse indicates the lapse between '...
                    'the activation of genes that the algorithm will consider to '...
                    'select the regulators of a gene. If time lapse is 1, GENIST '...
                    'assumes that genes that are active in one time point can regulate '...
                    'other genes in the following time point. '...
                    'If time lapse is 0, GENIST assumes that genes that are active in one '...
                    'time point can regulate other genes in the same time point. '...
                    'If time lapse is [0,1], GENIST assumes that genes that are active in one '...
                    'time point can regulate other genes in the same time point or in the '...
                    'following time point. Default is 1.'];
           elseif(listBoxValue=="7")
                app.GENIST_InputsInfoTextArea.Value = ['Reg Fold Change Threshold is the minimum (threshold) '...
                    'fold-change expression that a  '...
                    'gene must experience between two consecutive time points for it to be '...
                    'considered a potential regulator of another gene that is changing  '...
                    'expression in the same '...
                    '(time lapse = 0) or the next (time lapse = 1) time point. Default is 1.3.'];
            elseif(listBoxValue=="8")
                app.GENIST_InputsInfoTextArea.Value = ['Reg Time Percent is the minimum percentage '...
                    'of the total time points '...
                    'that a potential regulator and a target show simultaneous '...
                    '(if time lapse = 0) or consecutive (if time lapse = 1) changes of '...
                    'expression for a gene to be considered a potential regulator of the '...
                    'target gene. Default is 0.3.'];
            elseif(listBoxValue=="9")
                app.GENIST_InputsInfoTextArea.Value = ['Discretization Levels is the number of levels in '...
                    'which the time course expression data '...
                    'will be discretized for calculating the Bayesian probabilities. Must be an integer. Default is 2. '...
                    'Minimum allowed value is 2. Recommended values 2 or 3.'];
            elseif(listBoxValue=="10")
                app.GENIST_InputsInfoTextArea.Value = ['Reg Bottom Percentage sets the bottom percentage '...
                    'of regulations of the final '...
                    'network that will be considered too low to be displayed (will be '...
                    'discarded). Default is 0.2.'];
            end
        end

        % Button pushed function: GENIST_RunButton
        function GENIST_RunButtonPushed(app, event)
            tf1 = strcmp(app.GENIST_GenesFileEditField.Value, ''); % Check if file name inputs have been provided
            tf2 = strcmp(app.GENIST_TimeCourseFileEditField.Value, '');
            tf3 = strcmp(app.GENIST_ClusteringFileEditField.Value, '');
            tf4 = strcmp(app.GENIST_TranscriptionFactorsFileEditField.Value, '');
            tf5 = strcmp(app.GENIST_SymbolFileEditField.Value, '');
            tf6 = strcmp(app.GENIST_OutputFileEditField.Value, '');
            
            if(tf1||tf2||tf6) % Error if required inputs have not been provided
                message = ['Please provide all required inputs: genes, time course, '...
                    'and an output file name.'];
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
                
            % Run if required inputs have been provided
            app.GENIST_StatusField.Value = '';
                
            % Get required inputs
            genes_file = app.GENIST_GenesFileEditField.Value;
            time_course_file = app.GENIST_TimeCourseFileEditField.Value;
                
            if(exist(genes_file,'file') == 0) % Check if given files exist
                message = 'Provided genes file cannot be found.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
                
            if(exist(time_course_file,'file') == 0) % Check if given files exist
                message = 'Provided time course file cannot be found.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                return;
            end
                
            [~, ~, ext] = fileparts(genes_file); % Check if provided files are Excel files
            if(strcmp(ext, '.xlsx')==0)
                message = 'Provided genes file must be of type *.xlsx.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                return;
            end
                
            [~, ~, ext] = fileparts(time_course_file); % Check if provided files are Excel files
            if(strcmp(ext, '.xlsx')==0)
                message = 'Provided time course file must be of type *.xlsx.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                return;
            end
            
            % Get optional inputs
            if(app.GENIST_TimeLapseDropDown.Value == '1')
                time_lapse = 1;
            elseif(app.GENIST_TimeLapseDropDown.Value == '0')
                time_lapse = 0;
            else
                time_lapse = [0,1];
            end
            
            is_reg_fc_th = app.GENIST_RegFoldChangeThresholdEditField.Value;
            is_reg_time_percent = app.GENIST_RegTimePercentEditField.Value;
            n_levels = app.GENIST_NLevelsEditField.Value;
            is_low_conn = app.GENIST_RegBottomPercentageEditField.Value;
                
            if(tf3) % Check if user has input optional file
                clustering_data_file = [];
            else
                clustering_data_file = app.GENIST_ClusteringFileEditField.Value;
                if(exist(clustering_data_file, 'file') == 0) % Check if given files exist
                    message = 'Provided clustering data file cannot be found.';
                    uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                    return;
                end
                [~,~,ext] = fileparts(clustering_data_file); % Check if provided files are Excel files
                if(strcmp(ext, '.xlsx') == 0)
                    message = 'Provided clustering data file must be of type *.xlsx.';
                    uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                    return;
                end
            end
            
            if(tf4) % Check if user has input optional file
                TF_file = [];
            else
                TF_file = app.GENIST_TranscriptionFactorsFileEditField.Value;
                if(exist(TF_file,'file') == 0) % Check if given files exist
                    message = 'Provided transcription factor file cannot be found.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                [~,~,ext] = fileparts(TF_file); % Check if provided files are Excel files
                if(strcmp(ext,'.xlsx')==0)
                    message = 'Provided transcription factors file must be of type *.xlsx.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
            end
            
            if(tf5) % Check if user has input optional file
                symbol_file = [];
            else
                symbol_file = app.GENIST_SymbolFileEditField.Value;
                if(exist(symbol_file,'file') == 0) % Check if given files exist
                    message = 'Provided gene name file cannot be found.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
                [~,~,ext] = fileparts(symbol_file); % Check if provided files are Excel files
                if(strcmp(ext,'.xlsx')==0)
                    message = 'Provided gene name file must be of type *.xlsx.';
                    uialert(app.UIFigure,message,'Error','Icon','error');
                    return;
                end
            end
            
            outfile = app.GENIST_OutputFileEditField.Value;
            
            % Run algorithm using inputs
            warning off
            app.GENIST_StatusField.Value = 'Working...';
            try % Try/catch block to catch errors during algorithm runtime
                GENIST2_0(genes_file,time_course_file,clustering_data_file,time_lapse,...
                TF_file,symbol_file,is_reg_fc_th,...
                is_reg_time_percent,n_levels,is_low_conn,outfile);
            catch ME % Display error info (ID,message,stack) if an error occurs
                uialert(app.UIFigure,getReport(ME,'extended','hyperlinks','off'),'Error Info','Icon','error');
                app.GENIST_StatusField.Value = 'Error';
                error_message = ['An error occurred and the algorithm was unable to finish running. '...
                    'Please ensure that all input data files are correctly '...
                    'formatted and that the correct input data files have been selected.'];
                uialert(app.UIFigure,error_message,'Runtime Error','Icon','error');
                return;
            end
            
            app.GENIST_StatusField.Value = 'Done';
            success_message = ['Results can be found in the current working Matlab directory.'];
            uialert(app.UIFigure,success_message,'Success','Icon','success');
        end

        % Button pushed function: GENIST_SymbolFileButton
        function GENIST_SymbolFileButtonPushed(app, event)
            [SymbolFile,SymbolFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(SymbolFile==0))
                app.GENIST_SymbolFileEditField.Value = strcat(SymbolFilePathname,SymbolFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: GENIST_TFFileButton
        function GENIST_TFFileButtonPushed(app, event)
            [TFFile,TFFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(TFFile==0))
                app.GENIST_TranscriptionFactorsFileEditField.Value = strcat(TFFilePathname,TFFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: GENIST_TimeCourseFileButton
        function GENIST_TimeCourseFileButtonPushed(app, event)
            [TimeCourseFile,TimeCourseFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(TimeCourseFile==0))
                app.GENIST_TimeCourseFileEditField.Value = strcat(TimeCourseFilePathname,TimeCourseFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: GENIST_GenesFileButton
        function GENIST_GenesFileButtonPushed(app, event)
            [GenesFile, GenesFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(GenesFile==0))
                app.GENIST_GenesFileEditField.Value = strcat(GenesFilePathname,GenesFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: RTP_ClusteringFileButton
        function RTP_ClusteringFileButtonButtonPushed(app, event)
            [ClusteringFile, ClusteringFilePathname] = uigetfile('*.xlsx; *.csv', ''); % Prompt user to choose file via file explorer
            if(not(ClusteringFile==0))
                app.RTP_ClusteringFileEditField.Value = strcat(ClusteringFilePathname,ClusteringFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
            ClusteringFile = app.RTP_ClusteringFileEditField.Value;
            % Enable/disable clustering options if file has been provided
            if(strcmp(ClusteringFile, '') == 0)
                app.RTP_ConnectHubsCheckBox.Enable = 'on';
                app.RTP_ClusteringSeedEditField.Enable = 'on';
                app.RTP_ClusteringTypeDropDown.Enable = 'on';
            else
                app.RTP_ConnectHubsCheckBox.Enable = 'off';
                app.RTP_ClusteringSeedEditField.Enable = 'off';
                app.RTP_ClusteringTypeDropDown.Enable = 'off';
            end
        end

        % Button pushed function: RTP_GeneExpressionFileButton
        function RTP_GeneExpressionFileButtonButtonPushed(app, event)
            [GeneExpressionFile,GeneExpressionFilePathname] = uigetfile('*.xlsx; *.csv', ''); % Prompt user to choose file via file explorer
            if(not(GeneExpressionFile==0))
                app.RTP_GeneExpressionFileEditField.Value = strcat(GeneExpressionFilePathname,GeneExpressionFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: RTP_GeneSymbolsFileButton
        function RTP_GeneSymbolsFileButtonButtonPushed(app, event)
            [SymbolsFile,SymbolsFilePathname] = uigetfile('*.xlsx; *.csv', ''); % Prompt user to choose file via file explorer
            if(not(SymbolsFile==0))
                app.RTP_GeneSymbolsFileEditField.Value = strcat(SymbolsFilePathname,SymbolsFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: RTP_InfoListBox
        function RTP_InfoListBoxValueChanged(app, event)
            value = app.RTP_InfoListBox.Value; % Change info text box based on user selection
            if(value == "0")
                app.RTP_InputsInfoTextArea.Value = ['Genes File must be an Excel or .csv file containing '...
                    'the list of genes to be included in the network as the first column. The files containing '...
                    'genes of DEGs returned by TuxOP,  in the TUX tab, can be used as the Genes File.'];
            elseif(value == "1")
                app.RTP_InputsInfoTextArea.Value = ['Gene Expression File must be an Excel or .csv file '...
                    'containing the expression data being used for the GRN '...
                    'inference. Each row must correspond to one gene, and the gene names must be included '...
                    'in the first column. The file can contain '...
                    'any number of genes, and RTP-STAR selects the genes specified in the genes file. '...
                    'The file gene_expression.xlsx returned by TuxOP, in the TUX tab, '...
                    'can be used as the Gene Expression File.'];
            elseif(value == "2")
                app.RTP_InputsInfoTextArea.Value = ['Gene Name File must be an Excel, .csv, or .txt file '...
                    'containing a list of genes in the '...
                    'first column, and a corresponding name for each gene in the second '...
                    'column. When Gene Name File is provided, the genes in the network for '...
                    'which a name was provided are represented by their names.'];
            elseif(value == "3")
                app.RTP_InputsInfoTextArea.Value = ['Transcription Factors File must be an Excel file '...
                    'containing a list of transcription factors as in the first column of the file. '...
                    'If transcription factors file is provided, only the genes '...
                    '(from the genes file) that '...
                    'are also included in the transcription factors file will be considered '...
                    'as potential regulators in the network.'];
            elseif(value == "4")
                app.RTP_InputsInfoTextArea.Value = ['Clustering Data File must be an Excel, .csv, or .txt '... 
                    'file containing expression data of the genes across different '...
                    'spacial confinements, conditions, or treatments. Each row must correspond to 1 gene, '...
                    'and the gene names must be included in the first column. The '...
                    'file can contain any number of genes, and RTP-STAR selects the '...
                    'genes specified in the genes file. The names of the genes in all '...
                    '.xlsx files must be consistent.'...
                    'The file gene_expression.xlsx returned by TuxOP, in the TUX tab, after processing '...
                    'the clustering dataset, can be used as the Clustering Data File.'];
            elseif(value == "5")
                app.RTP_InputsInfoTextArea.Value = ['Time Course File must be an Excel, .csv, or .txt file '...
                    'containing expression data of the genes across different time or developmental '...
                    'time points. Each row must correspond to 1 gene, and the gene names must be included in the first column. '...
                    'The file can contain any number of genes, and RTP-STAR selects the genes specified in the genes file.'...
                    'The file gene_expression.xlsx returned by TuxOP, in the TUX tab, after processing '...
                    'a time-course dataset, can be used as the Time Course File.'];
            elseif(value == "6")
                app.RTP_InputsInfoTextArea.Value = ['Results File Name is the name that will be used '...
                    'to save the output file. '...
                    'The results of RTP-STAR will be stored in a .txt file with this name.'];
            elseif(value == "7")
                app.RTP_InputsInfoTextArea.Value = ['Connect Hubs indicates if the '...
                    'node of each cluster with the most output edges will be connected.'];
            elseif(value == "8")
                app.RTP_InputsInfoTextArea.Value = ['Clustering Seed allows the use of the '...
                    'same clusters repeatedly. Default value of 0 selects a new initialization value '...
                    'for performing clustering every run, which can lead to different clusters every run.'];
            elseif(value == "9")
                app.RTP_InputsInfoTextArea.Value = ['Clustering Type indicates which type of clustering is used: '...
                    'Spatial or Temporal.'];
            elseif(value == "10")
                app.RTP_InputsInfoTextArea.Value = ['Number of Iterations indicates the number of times '...
                    'to run RTP-STAR and combine results. Up to 100 iterations are recommended if spatial '...
                    'clustering is used. If temporal clustering is used, less than 10 iterations are recommended.'];
            elseif(value == "11")
                app.RTP_InputsInfoTextArea.Value = ['Edge Proportion indicates the proportion of edges to keep. '...
                    'This value is only used if the Number of Iterations is greater than 1.'];
            elseif(value == "12")
                app.RTP_InputsInfoTextArea.Value = ['Outputs Folder is the location where all '...
                    'output files will be moved after a run is completed.'];
            end
        end

        % Button pushed function: RTP_RunButton
        function RTP_RunButtonPushed(app, event)
            app.RTP_StatusField.Value = 'Preparing files...';
            
            % Input Files / Folders
            outputsFolder = app.RTP_OutputsFolderEditField.Value;
            expression_file = app.RTP_GeneExpressionFileEditField.Value;
            gene_file = app.RTP_GeneFileEditField.Value;
            clustering_file = app.RTP_ClusteringFileEditField.Value;
            symbol_file = app.RTP_GeneSymbolsFileEditField.Value;
            timecourse_file = app.RTP_TimeCourseFileEditField.Value;
            filenameResults = app.RTP_ResultsFileNameEditField.Value;
            TF_file = app.RTP_TFFileEditField.Value;
            
            % Collect file names
            if(strcmp(outputsFolder, '')) % Check if required filename has been provided
                message = 'Please provide an outputs folder.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                app.RTP_StatusField.Value = 'Error';
                return;
            end
            
            if(strcmp(gene_file, '')) % Check if required filename has been provided
                message = 'Please provide a genes file.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                app.RTP_StatusField.Value = 'Error';
                return;
            end
            
            if(strcmp(expression_file, '')) % Check if required filename has been provided
                message = 'Please provide a gene expression file.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                app.RTP_StatusField.Value = 'Error';
                return;
            end
            
            if(exist(outputsFolder, 'dir') == 0) % Check if given files exist
                message = 'Provided outputs folder cannot be found.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                app.RTP_StatusField.Value = 'Error';
                return;
            end
            
            if(exist(gene_file, 'file') == 0) % Check if given files exist
                message = 'Provided genes file cannot be found.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                app.RTP_StatusField.Value = 'Error';
                return;
            end
            
            if(exist(expression_file, 'file') == 0) % Check if given files exist
                message = 'Provided gene expression file cannot be found.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                app.RTP_StatusField.Value = 'Error';
                return;
            end
            
            if(strcmp(filenameResults, '')) % Check if output filename has been provided
                message = 'Please provide a results file name.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                app.RTP_StatusField.Value = 'Error';
                return;
            end
            
            if(strcmp(symbol_file, '')) % Check if optional file is given
                symbol_file = [];
            else
                if(exist(symbol_file, 'file') == 0) % Check if optional file exists
                    message = 'Provided gene name file cannot be found.';
                    uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                    app.RTP_StatusField.Value = 'Error';
                    return;
                end
            end
            
            if(strcmp(clustering_file, '')) % Check if optional file is given
                clustering_file = [];
                clusteringFlag = 0;
            else
                if(exist(clustering_file, 'file') == 0) % Check if optional file exists
                    message = 'Provided clustering file cannot be found.';
                    uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                    app.RTP_StatusField.Value = 'Error';
                    return;
                end
                clusteringFlag = 1;
            end
            
            if(strcmp(timecourse_file, '')) % Check if optional file is given
                timecourse_file = [];
            else
                if(exist(timecourse_file, 'file') == 0) % Check if optional file exists
                    message = 'Provided time course file cannot be found.';
                    uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                    app.RTP_StatusField.Value = 'Error';
                    return;
                end
            end
            
            expression = readtable(expression_file);
            expressionFlag = 0;
            if(strcmp(TF_file, '')) % Check if optional file is given
                % Check if TF column already exists
                columnsComp = strcmp('TF', expression.Properties.VariableNames);
                columnsFlag = any(columnsComp);
                % If there is no TF file and no TF column, add in a row of 0s
                if(columnsFlag == 0)
                    expression.TF = ones(size(expression, 1), 1);
                    expression = [expression(:, 1) expression(:, size(expression, 2)) expression(:, 2:size(expression, 2) - 1)];
                    writetable(expression, 'expressionWithTFs.xlsx');
                    expression_file = 'expressionWithTFs.xlsx';
                    expressionFlag = 1;
                end
            else
                if(exist(TF_file, 'file') == 0) % Check if optional file exists
                    message = 'Provided TF file cannot be found.';
                    uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                    app.RTP_StatusField.Value = 'Error';
                    return;
                end
                columnsComp = strcmp('TF', expression.Properties.VariableNames);
                columnsFlag = any(columnsComp);
                % Integrate TFs into expression file if there is no TF column
                if(columnsFlag == 0)
                    TFs = readtable(TF_file);
                    expression.TF = ismember(expression{:, 1}, TFs{:, 1});
                    expression = [expression(:, 1) expression(:, size(expression, 2)) expression(:, 2:size(expression, 2) - 1)];
                    writetable(expression, 'expressionWithTFs.xlsx');
                    expression_file = 'expressionWithTFs.xlsx';
                    expressionFlag = 1;
                end
            end
            
            % Optional clustering settings
            if(app.RTP_ClusteringSeedEditField.Value == 0)
                clusteringSeed = [];
            elseif(strcmp(app.RTP_ClusteringSeedEditField.Enable, 'off'))
                clusteringSeed = [];
            else
                clusteringSeed = app.RTP_ClusteringSeedEditField.Value;
            end
            connecthubs = app.RTP_ConnectHubsCheckBox.Value;
            clustering_type = app.RTP_ClusteringTypeDropDown.Value;
            
            % Required general settings
            numiters = app.RTP_NumitersEditField.Value;
            maxprop = app.RTP_MaxpropEditField.Value;        
            
            % Run algorithm using inputs
            app.RTP_StatusField.Value = 'Working...';
            try % Try/catch block to catch errors during algorithm runtime
                RTPSTAR_MAIN(numiters, maxprop, gene_file, expression_file, clustering_file, timecourse_file,...
                    symbol_file, connecthubs, clusteringSeed, clustering_type, [], [], filenameResults);
            catch ME % Display error info if an error occurs
                uialert(app.UIFigure, getReport(ME, 'extended', 'hyperlinks', 'off'), 'Error Info', 'Icon', 'error');
                app.RTP_StatusField.Value = 'Error';
                error_message = ['An error occurred and the algorithm was unable to finish running. '...
                    'Please ensure that all input data files are correctly '...
                    'formatted and that the correct input data files have been selected.'];
                uialert(app.UIFigure,error_message, 'Runtime Error', 'Icon', 'error');
                return;
            end
            
            % Move output files to inputs folder
            if(clusteringFlag)
                outputFile = "clusters*.csv";
                outputFile = char(outputFile);
                movefile(outputFile, outputsFolder);
            end
            
            if(expressionFlag)
                outputFile = "expressionWithTFs.xlsx";
                outputFile = char(outputFile);
                movefile(outputFile, outputsFolder);
            end
            
            if(numiters > 1)
                outputFile = "biograph*.txt";
                outputFile = char(outputFile);
                movefile(outputFile, outputsFolder);
            end
            
            [~, name, ext] = fileparts(filenameResults);
            name = strcat(name, "*");
            outputFile = strcat(name, ext);
            outputFile = char(outputFile);
            movefile(outputFile, outputsFolder);
            
            % Update GUI with status and create a popup
            app.RTP_StatusField.Value = 'Done';
            message = 'Results can be found in the Outputs Folder.';
            uialert(app.UIFigure, message, 'Success', 'Icon', 'success');
        end

        % Button pushed function: Tux_CleaningInputsFolderButton
        function Tux_CleaningInputsFolderButtonPushed(app, event)
            InputFolder = uigetdir; % Prompt user to choose file via file explorer
            if(InputFolder == 0)
                app.UIFigure.Visible = 'off'; % Reopen GUI
                app.UIFigure.Visible = 'on';
                return;
            end
            if ~isdir(InputFolder)
                message = 'Provided inputs folder cannot be found.';
                uialert(app.UIFigure,message,'Error','Icon','error');
            end
            app.Tux_CleaningInputsFolderEditField.Value = InputFolder; % Set edit field to choosen file name
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: Tux_CufflinksFASTAFileButton
        function Tux_CufflinksFASTAFileButtonPushed(app, event)
            [FASTAFile, FASTAFilePathname] = uigetfile({'*.fa;*.fasta'}); % Prompt user to choose file via file explorer
            if(not(FASTAFile == 0))
                app.Tux_CufflinksFASTAFileEditField.Value = strcat(FASTAFilePathname,FASTAFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: Tux_RunButton_TuxOP
        function Tux_RunButton_TuxOPPushed(app, event)
            % CuffOutProcess
            warning off
            repfile = 'FPKM_replicates.xlsx';
            MainFolder = app.Tux_CleaningInputsFolderEditField.Value;
            inputFolders = dir(MainFolder); % List of all files and folders in input folder
            dirFlags = [inputFolders.isdir]; % Logical vector that tells which is a directory
            subFolders = inputFolders(dirFlags); % Extract only directories
            subFolders(ismember({subFolders.name}, {'.', '..', 'diff_out', 'merged_asm'})) = []; % Remove uneeded directories
            subFolders(startsWith({subFolders.name}, 'Cuff')) = [];
            
            if (strcmp(MainFolder,'')) % Check if filename has been provided
                message = 'Please provide an input folder name.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            
            if (exist(MainFolder,'dir') == 0) % Check if given files exist
                message = 'Provided input folder cannot be found.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            
            tf1 = strcmp(app.Tux_SampleGroup1EditField.Value, ''); % Check that comparison inputs have been provided
            tf2 = strcmp(app.Tux_SampleGroup2EditField.Value, '');
            
            if(tf1||tf2) % Error if required inputs have not been provided
                message = ['Please provide both sample groups for comparison.'];
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
                return;
            end
            
            app.Tux_StatusField_TuxOP.Value = "TuxOP...";
            
            j = 1;
            while(j <= length(subFolders))
                filePattern = fullfile(MainFolder, subFolders(j).name, '*.fastq*');
                gzFiles = dir(filePattern); % Find all .gz files in provided folder
                a = length(gzFiles);
                k = 1;
                while(k <= length(gzFiles)) % Create merged replicates file
                    if(j==1 && k==1) % Create file
                        fileold = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.fpkm_tracking"; % Change file extension to .txt
                        filenew = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.txt";
                        filenew = char(filenew);
                        fileold = char(fileold);
                        copyfile(fileold,filenew);
                        file1 = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.txt";
                        sample1name = subFolders(j).name + "_" + k;
                        k = k + 1;
                        if(k > length(gzFiles))
                            k = 1;
                            j = j + 1;
                        end
                        fileold = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.fpkm_tracking"; % Change file extension to .txt
                        filenew = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.txt";
                        filenew = char(filenew);
                        fileold = char(fileold);
                        copyfile(fileold,filenew);
                        file2 = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.txt";
                        sample2name = subFolders(j).name + "_" + k;
                        k = k + 1;
                        if(k > length(gzFiles))
                            k = 1;
                            j = j + 1;
                        end
                        
                        %sample1name = strcat("FPKM_", sample1name);
                        %sample2name = strcat("FPKM_", sample2name);
                        fpkm_replicates_init(file1, file2, sample1name, sample2name); % First time through run init version
                    else % Append to file
                        fileold = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.fpkm_tracking"; % Change file extension to .txt
                        filenew = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.txt";
                        filenew = char(filenew);
                        fileold = char(fileold);
                        copyfile(fileold,filenew);
                        file2 = MainFolder + "/CufflinksOut" + subFolders(j).name + "_" + k + "/genes.txt";
                        sample2name = subFolders(j).name + "_" + k;
                        
                        %sample2name = strcat("FPKM_", sample2name);
                        fpkm_replicates(repfile, file2, sample2name); % Normal version
                        k = k + 1;
                    end
                end
                j = j + 1;
            end
            
            mk_length = 0; % Generate full mk list
            for j = 1 : length(subFolders) % Determine number of markers
                filePattern = fullfile(MainFolder, subFolders(j).name, '*.fastq*');
                gzFiles = dir(filePattern); % Find all .gz files in provided folder
                if(~isempty(gzFiles))
                    mk_length = mk_length + 1;
                end
            end
            mk = cell(1, mk_length);
            mk_counter = 1;
            for j = 1 : length(subFolders) % Create marker list
                filePattern = fullfile(MainFolder, subFolders(j).name, '*.fastq*');
                gzFiles = dir(filePattern); % Find all .gz files in provided folder
                if(~isempty(gzFiles)) % If folder contains .gz files, add to mk list
                    %mk(1, mk_counter) = cellstr(strcat("DEG_", subFolders(j).name));
                    mk(1, mk_counter) = cellstr(subFolders(j).name);
                    mk_counter = mk_counter + 1;
                end
            end
            
            % Comparison Inputs
            FC_th = app.Tux_CleaningQualityThresholdEditField_1.Value;
            q_th = app.Tux_CleaningQualityThresholdEditField_2.Value;
            mk_interest = strsplit(char(app.Tux_SampleGroup1EditField.Value), {', ', ','}); % Split string based on ',' and ', ' delimiters
            mk_compare = strsplit(char(app.Tux_SampleGroup2EditField.Value), {', ', ','}); % Split string based on ',' and ', ' delimiters
            %mk_interest = strcat("DEG_", mk_interest);
            %mk_compare = strcat("DEG_", mk_compare);
            symbol_file = app.Tux_SymbolFileEditField.Value;
            TF_file = app.Tux_TFFileEditField.Value;
            filename_old = char(MainFolder + "/diff_out/gene_exp.diff"); % Change file extension to .txt
            filename = char(MainFolder + "/diff_out/gene_exp.txt");
            copyfile(filename_old, filename);
            
            find_DEG(mk_interest, mk_compare, mk, filename, q_th, FC_th, TF_file, symbol_file) % Perform desired comparison
            
            outputfile = "gene_expression.xlsx"; % Move output files to inputs folder
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            
            outputfile = "data_DE.txt";
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            
            outputfile = "complete_table.xlsx";
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            
            outputfile = "FPKM_replicates.xlsx";
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            
            filename_compare = mk_compare(1); % Determine filename components
            if length(mk_compare) > 1
                for i = 2:length(mk_compare)
                    filename_compare = char(strcat(filename_compare,'_',mk_compare(i)));
                end
            end
            filename_interest = mk_interest(1); % Determine filename components
            if length(mk_interest) > 1
                for i = 2:length(mk_interest)
                    filename_interest = char(strcat(filename_interest,'_',mk_interest(i)));
                end
            end
            outputfile = char(strcat('DEG_', filename_interest, '_vs_', filename_compare, '.xlsx')); % Move output files to inputs folder
            movefile(outputfile,MainFolder);
            outputfile = char(strcat('DEG_', filename_compare, '_vs_', filename_interest, '.xlsx')); % Move output files to inputs folder
            movefile(outputfile, MainFolder);
            
            app.Tux_StatusField_TuxOP.Value = "TuxOP Finished";
            warning on
        end

        % Button pushed function: Tux_TophatGTFGFFFileButton
        function Tux_TophatGTFGFFFileButtonPushed(app, event)
            [GTFGFFFile, GTFGFFFilePathname] = uigetfile({'*.gtf;*.gff;*.gff3'}); % Prompt user to choose file via file explorer
            if(not(GTFGFFFile==0))
                app.Tux_TophatGTFGFFFileEditField.Value = strcat(GTFGFFFilePathname,GTFGFFFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: GENIST_ClusteringFileButton
        function GENIST_ClusteringFileButtonPushed(app, event)
            [ClusteringFile, ClusteringFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(ClusteringFile==0))
                app.GENIST_ClusteringFileEditField.Value = strcat(ClusteringFilePathname,ClusteringFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: RTP_TimeCourseFileButton
        function RTP_TimeCourseFileButtonPushed(app, event)
            [TimeCourseFile, TimeCourseFilePathname] = uigetfile('*.xlsx; *.csv', ''); % Prompt user to choose file via file explorer
            if(not(TimeCourseFile==0))
                app.RTP_TimeCourseFileEditField.Value = strcat(TimeCourseFilePathname, TimeCourseFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: RTP_GeneFileButton
        function RTP_GeneFileButtonPushed(app, event)
            [GeneFile, GeneFilePathname] = uigetfile('*.xlsx; *.csv', ''); % Prompt user to choose file via file explorer
            if(not(GeneFile==0))
                app.RTP_GeneFileEditField.Value = strcat(GeneFilePathname, GeneFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: RTP_ClusteringFileEditField
        function RTP_ClusteringFileEditFieldValueChanged(app, event)
            ClusteringFile = app.RTP_ClusteringFileEditField.Value; % Enable/disable clutsering options if file has been provided
            if(strcmp(ClusteringFile, '') == 0)
                app.RTP_ConnectHubsCheckBox.Enable = 'on';
                app.RTP_ClusteringSeedEditField.Enable = 'on';
                app.RTP_ClusteringTypeDropDown.Enable = 'on';
            else
                app.RTP_ConnectHubsCheckBox.Enable = 'off';
                app.RTP_ClusteringSeedEditField.Enable = 'off';
                app.RTP_ClusteringTypeDropDown.Enable = 'off';
            end
        end

        % Button pushed function: RTP_TFFileButton
        function RTP_TFFileButtonPushed(app, event)
            [TFFile, TFFilePathname] = uigetfile('*.xlsx; *.csv', ''); % Prompt user to choose file via file explorer
            if(not(TFFile==0))
                app.RTP_TFFileEditField.Value = strcat(TFFilePathname, TFFile);
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: Tux_RunButton_Tuxedo
        function Tux_RunButton_TuxedoPushed(app, event)
            % Manual Command Line Entry
            if(strcmp(app.Tux_CommandLineTextArea.Value, '') == 0)
                system(char(app.Tux_CommandLineTextArea.Value));
                return
            end
            
            % Inputs and Options
            Threads = app.Tux_TophatThreadsEditField.Value;
            GTFFile = app.Tux_TophatGTFGFFFileEditField.Value;
            LibraryType = app.Tux_TophatLibraryTypeDropDown.Value;
            if(strcmp(LibraryType, 'fr-unstranded'))
                LibraryType = '';
            end
            if(strcmp(LibraryType, 'fr-firststrand'))
                LibraryType = '--rna-strandness R';
            end
            if(strcmp(LibraryType, 'fr-secondstrand'))
                LibraryType = '--rna-strandess F';
            end
            
            FASTAFile = app.Tux_CufflinksFASTAFileEditField.Value;
            MainFolder = app.Tux_CleaningInputsFolderEditField.Value;
            QualityThreshold = app.Tux_CleaningQualityThresholdEditField.Value;
            MinSeqLength = app.Tux_CleaningMinSeqLengthEditField.Value;
            WindowSize = app.Tux_CleaningWindowSizeEditField.Value;
            
            % Set Matlab PATH variable so .exe files can be run
            % chmod -R +x TuxNet to allow owner executable access to all files
            path1 = getenv('PATH');
            path1 =[path1 ':'];
            path1 = [path1 pwd];
            setenv('PATH', path1);
            
            if(strcmp(MainFolder,'')) % Check if filename has been provided
                message = 'Please provide an input folder name.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            
            if(strcmp(GTFFile,'')) % Check if filename has been provided
                message = 'Please provide a reference genome file name.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            
            if(strcmp(FASTAFile,'')) % Check if filename has been provided
                message = 'Please provide a FASTA file name.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            
            if(exist(MainFolder,'dir') == 0) % Check if given files exist
                message = 'Provided input folder cannot be found.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            
            if(exist(GTFFile,'file') == 0) % Check if given files exist
                message = 'Provided reference genome file cannot be found.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            
            if(exist(FASTAFile,'file') == 0) % Check if given files exist
                message = 'Provided FASTA file cannot be found.';
                uialert(app.UIFigure,message,'Error','Icon','error');
                return;
            end
            
            MainFolder_NoSpace = strrep(MainFolder, ' ', '*'); % Replace spaces with stars
            %FASTAFile = strrep(FASTAFile, ' ', '*');
            %GTFFile = strrep(GTFFile, ' ', '*');
            
            % Cleaning
            inputFolders = dir(MainFolder); % List of all files and folders in input folder
            dirFlags = [inputFolders.isdir]; % Logical vector that tells which is a directory
            subFolders = inputFolders(dirFlags); % Extract only directories
            subFolders(ismember({subFolders.name}, {'.', '..'})) = []; % Remove current directory and up on directory
            
            for j = 1 : length(subFolders)
                filePattern = fullfile(MainFolder, subFolders(j).name, '*.fastq*');
                gzFiles = dir(filePattern); % Find all .fastq files in provided folder
                
                for k = 1 : length(gzFiles) % Run cleaning algorithm on each found .gz file
                    baseFileName = gzFiles(k).name;
                    fullFileName = fullfile(MainFolder, subFolders(j).name, baseFileName);
                    app.Tux_StatusField_Tuxedo.Value = "Cleaning " + baseFileName + "...";
                    command_Cleaning = "./ea-utils.1.1.2-686/fastq-mcf IlluminaAdaptorSeq.fasta " +...
                        "'" + fullFileName + "'" + " -q " + QualityThreshold +...
                        " -l " + MinSeqLength + " -w " + WindowSize +...
                        " -o " + "Cleaned" + subFolders(j).name + "_" + k + ".fastq;";
                    status_Cleaning = system(command_Cleaning);
                    if(status_Cleaning~=0) % If command failed to execute
                        app.Tux_StatusField_Tuxedo.Value = "Cleaning Failed on " + baseFileName;
                        return;
                    end

                    outputfile = "Cleaned" + subFolders(j).name + "_" + k + ".fastq"; % Move output files to inputs folder
                    outputfile = char(outputfile);
                    movefile(outputfile, MainFolder);
                end
            end
            
            app.Tux_StatusField_Tuxedo.Value = 'Cleaning Successful on all files';
            
            % hisat2
            app.Tux_StatusField_Tuxedo.Value = "hisat2-build...";
            command_build = "./hisat2-2.1.0/extract_splice_sites.py " + GTFFile + " > sites.ss";
            status_build = system(command_build);
            if(status_build ~= 0) % If command failed to execute
                app.Tux_StatusField_Tuxedo.Value = "extract_splice_sites Failed";
                return;
            end
            command_build = "./hisat2-2.1.0/extract_exons.py " + GTFFile + " > exons.exon";
            status_build = system(command_build);
            if(status_build ~= 0) % If command failed to execute
                app.Tux_StatusField_Tuxedo.Value = "extract_exons Failed";
                return;
            end
            command_build = "./hisat2-2.1.0/hisat2-build --ss sites.ss --exon exons.exon " +...
                FASTAFile + " index"; % Build reference genome with hisat2-build
            status_build = system(command_build);
            if(status_build ~= 0) % If command failed to execute
                app.Tux_StatusField_Tuxedo.Value = "hisat2-build Failed";
                return;
            end
            
            for j = 1 : length(subFolders)
                filePattern = fullfile(MainFolder, subFolders(j).name, '*.fastq*');
                gzFiles = dir(filePattern); % Find all .fastq files in provided folder
                MainFolder = fullfile(MainFolder);
                
                 for k = 1 : length(gzFiles)
                    app.Tux_StatusField_Tuxedo.Value = "hisat2 " + "Cleaned" + subFolders(j).name + "_" + k + ".fastq...";
                    command_hisat2 = "./hisat2-2.1.0/hisat2 --dta-cufflinks -p " + Threads + " -x index " +...
                        LibraryType + " -U '" + MainFolder_NoSpace + "/Cleaned" + subFolders(j).name + "_" + k + ".fastq'" + " -S " +...
                        "hisat2Out" + subFolders(j).name + "_" + k + ".sam";
                    status_hisat2 = system(command_hisat2); % Run hisat2
                    if(status_hisat2 ~= 0) % If command failed to execute
                        app.Tux_StatusField_Tuxedo.Value = "hisat2 Failed on " + "Cleaned" + subFolders(j).name + "_" + k + ".fastq";
                        return;
                    end

                    app.Tux_StatusField_Tuxedo.Value = "samtools sam to bam " + "hisat2Out" + subFolders(j).name +...
                        "_" + k + ".sam...";
                    command_samtools = "./samtools-1.2/samtools view -bS hisat2Out" + subFolders(j).name + "_" + k + ".sam > hisat2Out" +...
                        subFolders(j).name + "_" + k + ".bam";
                    status_samtools = system(command_samtools); % Convert sam to bam
                    if(status_samtools ~= 0) % If command failed to execute
                        app.Tux_StatusField_Tuxedo.Value = "samtools sam to bam Failed on " +...
                            "hisat2Out" + subFolders(j).name + "_" + k + ".sam";
                        return;
                    end

                    app.Tux_StatusField_Tuxedo.Value = "samtools sort bam " + "hisat2Out" + subFolders(j).name +...
                         "_" + k + ".bam...";
                    command_samtools = "./samtools-1.2/samtools sort -T temp -o hisat2OutSorted" +...
                        subFolders(j).name + "_" + k + ".bam hisat2Out" + subFolders(j).name + "_" + k + ".bam";
                    status_samtools = system(command_samtools);
                    if(status_samtools ~= 0) % If command failed to execute
                        app.Tux_StatusField_Tuxedo.Value = "samtools sort Failed on " +...
                            "hisat2Out" + subFolders(j).name + "_" + k + ".bam";
                        return;
                    end
                     
                    outputfile = "hisat2Out" + subFolders(j).name + "_" + k + ".bam"; % Move output files to inputs folder
                    outputfile = char(outputfile);
                    movefile(outputfile, MainFolder);
                    outputfile = "hisat2OutSorted" + subFolders(j).name + "_" + k + ".bam"; % Move output files to inputs folder
                    outputfile = char(outputfile);
                    movefile(outputfile, MainFolder);
                    outputfile = "hisat2Out" + subFolders(j).name + "_" + k + ".sam"; % Move output files to inputs folder
                    outputfile = char(outputfile);
                    movefile(outputfile, MainFolder);
                end
            end
            
            for i = 1:8 % Move each index file to input folder
                indexfile = "index." + i + ".ht2";
                indexfile = char(indexfile);
                movefile(indexfile, MainFolder);
            end
            outputfile = "sites.ss"; % Move output files to inputs folder
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            outputfile = "exons.exon"; % Move output files to inputs folder
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            
            app.Tux_StatusField_Tuxedo.Value = 'hisat2 Successful on all files';
            
            % Cufflinks
            for j = 1 : length(subFolders)
                filePattern = fullfile(MainFolder, subFolders(j).name, '*.fastq*');
                gzFiles = dir(filePattern); % Find all .gz files in provided folder
                
                for k = 1 : length(gzFiles)
                    app.Tux_StatusField_Tuxedo.Value = "Cufflinks " + "hisat2OutSorted" + subFolders(j).name + "_" + k + "...";
                    command_Cufflinks = "./cufflinks -p " + Threads + " -G " +...
                        GTFFile + " -b " + FASTAFile + " -o " +...
                        "CufflinksOut" + subFolders(j).name + "_" + k + " "...
                        + "'" + MainFolder + "/hisat2OutSorted" + subFolders(j).name + "_" + k + ".bam';";
                    status_Cufflinks = system(command_Cufflinks);
                    if(status_Cufflinks ~= 0) % If command failed to execute
                        app.Tux_StatusField_Tuxedo.Value = "Cufflinks Failed on " + "hisat2OutSorted" + subFolders(j).name + "_" + k;
                        return
                    end
                    
                    outputfile = "CufflinksOut" + subFolders(j).name + "_" + k; % Move output files to inputs folder
                    outputfile = char(outputfile);
                    movefile(outputfile, MainFolder);
                    
                    if((j == 1) && (k == 1)) % Generate assemblies.txt file
                        fid = fopen('assemblies.txt', 'w');
                        fprintf(fid, MainFolder + "/CufflinksOut" + subFolders(j).name + "_%d/transcripts.gtf\n", k);
                        fclose(fid);
                    else % Append assemblies.txt file
                        fid = fopen('assemblies.txt', 'a');
                        fprintf(fid, MainFolder + "/CufflinksOut" + subFolders(j).name + "_%d/transcripts.gtf\n", k);
                        fclose(fid);
                    end
                end
            end
            
            outputfile = "assemblies.txt"; % Move output files to inputs folder
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            
            app.Tux_StatusField_Tuxedo.Value = 'Cufflinks Successful on all files';
            
            % Cuffmerge
            app.Tux_StatusField_Tuxedo.Value = "Cuffmerge...";
            command_Cuffmerge = "./cuffmerge -g " + GTFFile +...
                " -s " + FASTAFile + " -p " + Threads + " " + "'" +...
                MainFolder + "/assemblies.txt';";
            status_Cuffmerge = system(command_Cuffmerge);
            if(status_Cuffmerge~=0) % If command failed to execute
                app.Tux_StatusField_Tuxedo.Value = "Cuffmerge Failed";
                return
            end
            outputfile = "merged_asm"; % Move output files to inputs folder
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            app.Tux_StatusField_Tuxedo.Value = 'Cuffmerge Successful';
            
            % Cuffdiff
            app.Tux_StatusField_Tuxedo.Value = "Cuffdiff...";
            GeneLabels = "";
            for j = 1 : length(subFolders) % Generate Gene Labels parameter
                filePattern = fullfile(MainFolder, subFolders(j).name, '*.fastq*');
                gzFiles = dir(filePattern); % Find all .fastq files in provided folder
                if(~isempty(gzFiles)) % If folder contains .fastq files, add to Gene Labels list
                    GeneLabels = GeneLabels + subFolders(j).name + ",";
                end
            end
            GeneLabels = regexprep(GeneLabels, '.$', '', 'lineanchors'); % Replace text using regular expression (remove extra ,)
            command_Cuffdiff = "./cuffdiff -o diff_out -b " + FASTAFile +...
                " -p " + Threads + " -L " + GeneLabels + " -u '" + MainFolder + "/merged_asm/merged.gtf' \" + newline;
            for j = 1 : length(subFolders)
                filePattern = fullfile(MainFolder, subFolders(j).name, '*.fastq*');
                gzFiles = dir(filePattern); % Find all .fastq files in provided folder
                for k = 1 : length(gzFiles) % Generate groupings
                    if(k == length(gzFiles))
                        command_Cuffdiff = command_Cuffdiff + "'" + MainFolder + "/hisat2OutSorted" +...
                            subFolders(j).name + "_" + k + ".bam' \" + "" + newline;
                    else
                        command_Cuffdiff = command_Cuffdiff + "'" + MainFolder + "/hisat2OutSorted" +...
                            subFolders(j).name + "_" + k + ".bam'" + ",";
                    end
                end
            end
            status_Cuffdiff = system(command_Cuffdiff);
            if(status_Cuffdiff~=0) % If command failed to execute
                app.Tux_StatusField_Tuxedo.Value = "Cuffdiff Failed";
                return
            end
            outputfile = "diff_out"; % Move output files to inputs folder
            outputfile = char(outputfile);
            movefile(outputfile, MainFolder);
            app.Tux_StatusField_Tuxedo.Value = 'Cuffdiff Successful';
        end

        % Button pushed function: Tux_TFFileButton
        function Tux_TFFileButtonPushed(app, event)
            [TFFile,TFFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(TFFile==0))
                app.Tux_TFFileEditField.Value = strcat(TFFilePathname,TFFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: Tux_SymbolFileButton
        function Tux_SymbolFileButtonPushed(app, event)
            [SymbolFile,SymbolFilePathname] = uigetfile('.xlsx'); % Prompt user to choose file via file explorer
            if(not(SymbolFile==0))
                app.Tux_SymbolFileEditField.Value = strcat(SymbolFilePathname,SymbolFile); % Set edit field to choosen file name
            end
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: RTP_OutputsFolderButton
        function RTP_OutputsFolderButtonPushed(app, event)
            outputsFolder = uigetdir; % Prompt user to choose file via file explorer
            if(outputsFolder == 0)
                app.UIFigure.Visible = 'off'; % Reopen GUI
                app.UIFigure.Visible = 'on';
                return;
            end
            if ~isdir(outputsFolder)
                message = 'Provided inputs folder cannot be found.';
                uialert(app.UIFigure, message, 'Error', 'Icon', 'error');
            end
            app.RTP_OutputsFolderEditField.Value = outputsFolder; % Set edit field to choosen file name
            app.UIFigure.Visible = 'off'; % Reopen GUI
            app.UIFigure.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'TuxNet';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TuxTab = uitab(app.TabGroup);
            app.TuxTab.Title = 'TUX';
            app.Tux_InfoTextArea = uitextarea(app.TuxTab);
            app.Tux_InfoTextArea.Editable = 'off';
            app.Tux_InfoTextArea.Value = {'TUX takes raw RNA-seq data (fastq files) selected by the user (files in input folder). Running the cleaning and modified Tuxedo steps trims the reads, removes short or low quality reads, maps the reads, obtains quantified gene expression, and performs differential expression analysis among all pairwise comparisons. Running TuxOP returns tables of gene normalized counts (FPKM values), FDR/log2(fold change) values associated to all pairwise comparisons in the data, and lists of DEGs using thresholds of fold change and q values specified by the user.  All the output files are saved in the input data folder.'};
            app.Tux_RunPanel_TuxOP = uipanel(app.TuxTab);
            app.Tux_RunButton_TuxOP = uibutton(app.Tux_RunPanel_TuxOP, 'push');
            app.Tux_RunButton_TuxOP.ButtonPushedFcn = createCallbackFcn(app, @Tux_RunButton_TuxOPPushed, true);
            app.Tux_RunButton_TuxOP.Text = 'Run';
            app.Tux_StatusField_TuxOP = uitextarea(app.Tux_RunPanel_TuxOP);
            app.Tux_StatusField_TuxOP.Editable = 'off';
            app.Tux_CleaningPanel = uipanel(app.TuxTab);
            app.Tux_CleaningPanel.Title = 'Cleaning Options';
            app.Tux_CleaningInputsFolderButton = uibutton(app.Tux_CleaningPanel, 'push');
            app.Tux_CleaningInputsFolderButton.ButtonPushedFcn = createCallbackFcn(app, @Tux_CleaningInputsFolderButtonPushed, true);
            app.Tux_CleaningInputsFolderButton.Text = 'Choose Folder';
            app.InputsFolderLabel = uilabel(app.Tux_CleaningPanel);
            app.InputsFolderLabel.Text = 'Inputs Folder';
            app.Tux_CleaningInputsFolderEditField = uieditfield(app.Tux_CleaningPanel, 'text');
            app.WindowSizeLabel = uilabel(app.Tux_CleaningPanel);
            app.WindowSizeLabel.Text = 'Window-Size';
            app.Tux_CleaningWindowSizeEditField = uieditfield(app.Tux_CleaningPanel, 'numeric');
            app.Tux_CleaningWindowSizeEditField.Limits = [0 Inf];
            app.Tux_CleaningWindowSizeEditField.Value = 5;
            app.QualityThresholdLabel = uilabel(app.Tux_CleaningPanel);
            app.QualityThresholdLabel.Text = 'Quality Threshold';
            app.Tux_CleaningQualityThresholdEditField = uieditfield(app.Tux_CleaningPanel, 'numeric');
            app.Tux_CleaningQualityThresholdEditField.Limits = [0 Inf];
            app.Tux_CleaningQualityThresholdEditField.ValueDisplayFormat = '%.0f';
            app.Tux_CleaningQualityThresholdEditField.Value = 30;
            app.MinSequenceLengthLabel = uilabel(app.Tux_CleaningPanel);
            app.MinSequenceLengthLabel.Text = 'Min Sequence Length';
            app.Tux_CleaningMinSeqLengthEditField = uieditfield(app.Tux_CleaningPanel, 'numeric');
            app.Tux_CleaningMinSeqLengthEditField.Limits = [0 Inf];
            app.Tux_CleaningMinSeqLengthEditField.ValueDisplayFormat = '%.0f';
            app.Tux_CleaningMinSeqLengthEditField.Value = 30;
            app.Tux_TophatPanel = uipanel(app.TuxTab);
            app.Tux_TophatPanel.Title = 'Tuxedo Options';
            app.ThreadsLabel = uilabel(app.Tux_TophatPanel);
            app.ThreadsLabel.Text = 'Threads';
            app.Tux_TophatThreadsEditField = uieditfield(app.Tux_TophatPanel, 'numeric');
            app.Tux_TophatThreadsEditField.Limits = [0 Inf];
            app.Tux_TophatThreadsEditField.ValueDisplayFormat = '%.0f';
            app.Tux_TophatThreadsEditField.Value = 1;
            app.Tux_TophatGTFGFFFileButton = uibutton(app.Tux_TophatPanel, 'push');
            app.Tux_TophatGTFGFFFileButton.ButtonPushedFcn = createCallbackFcn(app, @Tux_TophatGTFGFFFileButtonPushed, true);
            app.Tux_TophatGTFGFFFileButton.Text = 'Choose File';
            app.ReferenceGenomeLabel = uilabel(app.Tux_TophatPanel);
            app.ReferenceGenomeLabel.Text = 'Reference Genome';
            app.Tux_TophatGTFGFFFileEditField = uieditfield(app.Tux_TophatPanel, 'text');
            app.Tux_TophatGTFGFFFileEditField.Value = 'genes.gtf';
            app.LibraryTypeDropDownLabel = uilabel(app.Tux_TophatPanel);
            app.LibraryTypeDropDownLabel.Text = 'Library Type';
            app.Tux_TophatLibraryTypeDropDown = uidropdown(app.Tux_TophatPanel);
            app.Tux_TophatLibraryTypeDropDown.Items = {'fr-unstranded', 'fr-firststrand', 'fr-secondstrand'};
            app.Tux_TophatLibraryTypeDropDown.Value = 'fr-unstranded';
            app.Tux_CufflinksFASTAFileButton = uibutton(app.Tux_TophatPanel, 'push');
            app.Tux_CufflinksFASTAFileButton.ButtonPushedFcn = createCallbackFcn(app, @Tux_CufflinksFASTAFileButtonPushed, true);
            app.Tux_CufflinksFASTAFileButton.Text = 'Choose File';
            app.FASTAFileLabel = uilabel(app.Tux_TophatPanel);
            app.FASTAFileLabel.Text = 'FASTA File';
            app.Tux_CufflinksFASTAFileEditField = uieditfield(app.Tux_TophatPanel, 'text');
            app.Tux_CufflinksFASTAFileEditField.Value = 'genome.fa';
            app.Tux_TuxOPPanel = uipanel(app.TuxTab);
            app.Tux_TuxOPPanel.Title = 'TuxOP Options';
            app.FCThresholdLabel = uilabel(app.Tux_TuxOPPanel);
            app.FCThresholdLabel.Text = 'FC Threshold';
            app.Tux_CleaningQualityThresholdEditField_1 = uieditfield(app.Tux_TuxOPPanel, 'numeric');
            app.Tux_CleaningQualityThresholdEditField_1.Limits = [0 Inf];
            app.Tux_CleaningQualityThresholdEditField_1.ValueDisplayFormat = '%.0f';
            app.Tux_CleaningQualityThresholdEditField_1.Value = 2;
            app.FDRThresholdLabel = uilabel(app.Tux_TuxOPPanel);
            app.FDRThresholdLabel.Text = 'FDR Threshold';
            app.Tux_CleaningQualityThresholdEditField_2 = uieditfield(app.Tux_TuxOPPanel, 'numeric');
            app.Tux_CleaningQualityThresholdEditField_2.Limits = [0 Inf];
            app.Tux_CleaningQualityThresholdEditField_2.ValueDisplayFormat = '%.2f';
            app.Tux_CleaningQualityThresholdEditField_2.Value = 0.05;
            app.TFFileOptionalLabel_3 = uilabel(app.Tux_TuxOPPanel);
            app.TFFileOptionalLabel_3.Text = 'TF File (Optional)';
            app.Tux_TFFileEditField = uieditfield(app.Tux_TuxOPPanel, 'text');
            app.Tux_TFFileEditField.Value = 'Arabidopsis_TFs.xlsx';
            app.GeneNameFileOptionalLabel_3 = uilabel(app.Tux_TuxOPPanel);
            app.GeneNameFileOptionalLabel_3.Text = 'Gene Name File (Optional)';
            app.Tux_SymbolFileEditField = uieditfield(app.Tux_TuxOPPanel, 'text');
            app.Tux_SymbolFileEditField.Value = 'GeneNames.xlsx';
            app.Tux_TFFileButton = uibutton(app.Tux_TuxOPPanel, 'push');
            app.Tux_TFFileButton.ButtonPushedFcn = createCallbackFcn(app, @Tux_TFFileButtonPushed, true);
            app.Tux_TFFileButton.Text = 'Choose File';
            app.Tux_SymbolFileButton = uibutton(app.Tux_TuxOPPanel, 'push');
            app.Tux_SymbolFileButton.ButtonPushedFcn = createCallbackFcn(app, @Tux_SymbolFileButtonPushed, true);
            app.Tux_SymbolFileButton.Text = 'Choose File';
            app.SampleGroup1EditFieldLabel = uilabel(app.Tux_TuxOPPanel);
            app.SampleGroup1EditFieldLabel.Text = 'Sample Group 1';
            app.Tux_SampleGroup1EditField = uieditfield(app.Tux_TuxOPPanel, 'text');
            app.SampleGroup2EditFieldLabel = uilabel(app.Tux_TuxOPPanel);
            app.SampleGroup2EditFieldLabel.Text = 'Sample Group 2';
            app.Tux_SampleGroup2EditField = uieditfield(app.Tux_TuxOPPanel, 'text');
            app.Tux_CommandLinePanel = uipanel(app.TuxTab);
            app.Tux_CommandLinePanel.Title = 'Manual Command Line Entry (Recommended for Advanced Users)';
            app.Tux_CommandLineTextArea = uitextarea(app.Tux_CommandLinePanel);
            app.Tux_RunPanel_Tuxedo = uipanel(app.TuxTab);
            app.Tux_RunButton_Tuxedo = uibutton(app.Tux_RunPanel_Tuxedo, 'push');
            app.Tux_RunButton_Tuxedo.ButtonPushedFcn = createCallbackFcn(app, @Tux_RunButton_TuxedoPushed, true);
            app.Tux_RunButton_Tuxedo.Text = 'Run';
            app.Tux_StatusField_Tuxedo = uitextarea(app.Tux_RunPanel_Tuxedo);
            app.Tux_StatusField_Tuxedo.Editable = 'off';
            app.Tux_InstructionsPanel = uipanel(app.TuxTab);
            app.Tux_InstructionsPanel.Title = 'Instructions';
            app.Tux_InstructionsTextArea = uitextarea(app.Tux_InstructionsPanel);
            app.Tux_InstructionsTextArea.Editable = 'off';
            app.Tux_InstructionsTextArea.Value = {'To process raw RNA-seq data, choose a folder containing all the fastq files organized in sub-folders, one for each sample (all replicates for each sample in each sub-folder). Label each sub-folder with the name of the corresponding sample. '; ''; 'For details on the parameters for the cleaning and modified Tuxedo steps, refer to the fastq-mcf and Tuxedo manuals. '; ''; 'To process the output of Tuxedo and get differentially expressed genes (DEGs) and tables of normalized genes counts, run TuxOP. Select fold change (FC) and FDR (q value) thresholds to define differential expression. In Sample Group 1 and 2, select the samples that need to be compared to find DEGs (if more than one sample is selected, separate samples with commas). Sample names typed in Sample Groups must match the names of the input sub-folders. Interchanging the samples in Group 1 and 2 results in the same lists of DEGs.'; ''; 'The tables of normalized gene counts can be obtained by running TuxOP with any FC and FDR thresholds and samples groups values. '};
            app.GENISTTab = uitab(app.TabGroup);
            app.GENISTTab.Title = 'GENIST';
            app.GENIST_InfoTextArea = uitextarea(app.GENISTTab);
            app.GENIST_InfoTextArea.Editable = 'off';
            app.GENIST_InfoTextArea.Value = {'The GENIST algorithm infers regulations among provided genes. The regulations are calculated by applying a first step of clustering that groups the genes based on their co-expression in the Clustering Data File, followed by a second step of a Bayesian Network inference, which uses the data in the Time Course File. This algorithm requires a time course gene expression dataset as an input to infer regulations among genes. For inferring regulation without a time course, use the RTP-STAR pipeline. The output is a text file that can be uploaded into Cytoscape for visualization.'};
            app.GENIST_RunPanel = uipanel(app.GENISTTab);
            app.GENIST_RunButton = uibutton(app.GENIST_RunPanel, 'push');
            app.GENIST_RunButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_RunButtonPushed, true);
            app.GENIST_RunButton.Text = 'Run';
            app.GENIST_StatusField = uitextarea(app.GENIST_RunPanel);
            app.GENIST_StatusField.Editable = 'off';
            app.GENIST_InputsInfoPanel = uipanel(app.GENISTTab);
            app.GENIST_InputsInfoPanel.Title = 'Inputs Info';
            app.GENIST_InfoListBox = uilistbox(app.GENIST_InputsInfoPanel);
            app.GENIST_InfoListBox.Items = {'Genes File', 'Time Course File', 'Clustering File', 'TF File', 'Gene Name File', 'Output File Name', 'Time Lapse', 'Reg Fold Change Threshold', 'Reg Time Percent', 'Discretization Levels', 'Reg Bottom Percentage'};
            app.GENIST_InfoListBox.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.GENIST_InfoListBox.ValueChangedFcn = createCallbackFcn(app, @GENIST_InfoListBoxValueChanged, true);
            app.GENIST_InfoListBox.Value = '0';
            app.GENIST_InputsInfoTextArea = uitextarea(app.GENIST_InputsInfoPanel);
            app.GENIST_InputsInfoTextArea.Editable = 'off';
            app.GENIST_InputsInfoTextArea.Value = {'Genes File must be an Excel file containing the list of genes to be included in the network as a column. The files containing genes of DEGs returned by TuxOP,  in the TUX tab, can be used as the Genes File.'};
            app.GENIST_OptionalInputsPanel = uipanel(app.GENISTTab);
            app.GENIST_OptionalInputsPanel.Title = 'Parameters';
            app.TimeLapseDropDownLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.TimeLapseDropDownLabel.Text = 'Time Lapse';
            app.GENIST_TimeLapseDropDown = uidropdown(app.GENIST_OptionalInputsPanel);
            app.GENIST_TimeLapseDropDown.Items = {'1', '0', '[0,1]'};
            app.GENIST_TimeLapseDropDown.Value = '1';
            app.RegFoldChangeThresholdEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.RegFoldChangeThresholdEditFieldLabel.Text = 'Reg Fold Change Threshold';
            app.GENIST_RegFoldChangeThresholdEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'numeric');
            app.GENIST_RegFoldChangeThresholdEditField.Limits = [0 Inf];
            app.GENIST_RegFoldChangeThresholdEditField.Value = 1.3;
            app.RegTimePercentEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.RegTimePercentEditFieldLabel.Text = 'Reg Time Percent';
            app.GENIST_RegTimePercentEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'numeric');
            app.GENIST_RegTimePercentEditField.Limits = [0 1];
            app.GENIST_RegTimePercentEditField.Value = 0.3;
            app.DiscretizationLevelsLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.DiscretizationLevelsLabel.Text = 'Discretization Levels';
            app.GENIST_NLevelsEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'numeric');
            app.GENIST_NLevelsEditField.Limits = [2 Inf];
            app.GENIST_NLevelsEditField.ValueDisplayFormat = '%.0f';
            app.GENIST_NLevelsEditField.Value = 2;
            app.RegBottomPercentageEditFieldLabel = uilabel(app.GENIST_OptionalInputsPanel);
            app.RegBottomPercentageEditFieldLabel.Text = 'Reg Bottom Percentage';
            app.GENIST_RegBottomPercentageEditField = uieditfield(app.GENIST_OptionalInputsPanel, 'numeric');
            app.GENIST_RegBottomPercentageEditField.Limits = [0 1];
            app.GENIST_RegBottomPercentageEditField.Value = 0.2;
            app.GENIST_RequiredInputsPanel = uipanel(app.GENISTTab);
            app.GENIST_RequiredInputsPanel.Title = 'Input Files';
            app.GENIST_GenesFileButton = uibutton(app.GENIST_RequiredInputsPanel, 'push');
            app.GENIST_GenesFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_GenesFileButtonPushed, true);
            app.GENIST_GenesFileButton.Text = 'Choose File';
            app.GENIST_TimeCourseFileButton = uibutton(app.GENIST_RequiredInputsPanel, 'push');
            app.GENIST_TimeCourseFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_TimeCourseFileButtonPushed, true);
            app.GENIST_TimeCourseFileButton.Text = 'Choose File';
            app.GENIST_ClusteringFileButton = uibutton(app.GENIST_RequiredInputsPanel, 'push');
            app.GENIST_ClusteringFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_ClusteringFileButtonPushed, true);
            app.GENIST_ClusteringFileButton.Text = 'Choose File';
            app.GenesFileEditFieldLabel = uilabel(app.GENIST_RequiredInputsPanel);
            app.GenesFileEditFieldLabel.Text = 'Genes File';
            app.GENIST_GenesFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.TimeCourseFileEditFieldLabel = uilabel(app.GENIST_RequiredInputsPanel);
            app.TimeCourseFileEditFieldLabel.Text = 'Time Course File';
            app.GENIST_TimeCourseFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.ClusteringFileOptionalLabel_2 = uilabel(app.GENIST_RequiredInputsPanel);
            app.ClusteringFileOptionalLabel_2.Text = 'Clustering File (Optional)';
            app.GENIST_ClusteringFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.OutputFileNameLabel = uilabel(app.GENIST_RequiredInputsPanel);
            app.OutputFileNameLabel.Text = 'Output File Name';
            app.GENIST_OutputFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.GENIST_OutputFileEditField.Value = 'cytoscape_table.txt';
            app.GENIST_TFFileButton = uibutton(app.GENIST_RequiredInputsPanel, 'push');
            app.GENIST_TFFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_TFFileButtonPushed, true);
            app.GENIST_TFFileButton.Text = 'Choose File';
            app.GENIST_SymbolFileButton = uibutton(app.GENIST_RequiredInputsPanel, 'push');
            app.GENIST_SymbolFileButton.ButtonPushedFcn = createCallbackFcn(app, @GENIST_SymbolFileButtonPushed, true);
            app.GENIST_SymbolFileButton.Text = 'Choose File';
            app.TFFileOptionalLabel_2 = uilabel(app.GENIST_RequiredInputsPanel);
            app.TFFileOptionalLabel_2.Text = 'TF File (Optional)';
            app.GENIST_TranscriptionFactorsFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.GENIST_TranscriptionFactorsFileEditField.Value = 'Arabidopsis_TFs.xlsx';
            app.GeneNameFileOptionalLabel_2 = uilabel(app.GENIST_RequiredInputsPanel);
            app.GeneNameFileOptionalLabel_2.Text = 'Gene Name File (Optional)';
            app.GENIST_SymbolFileEditField = uieditfield(app.GENIST_RequiredInputsPanel, 'text');
            app.GENIST_SymbolFileEditField.Value = 'GeneNames.xlsx';
            app.RTPSTARTab = uitab(app.TabGroup);
            app.RTPSTARTab.Title = 'RTP-STAR';
            app.RTP_InfoTextArea = uitextarea(app.RTPSTARTab);
            app.RTP_InfoTextArea.Editable = 'off';
            app.RTP_InfoTextArea.Value = {'Regression Tree Pipeline for Spatial, Temporal, and Replicate data (RTP-STAR) infers gene regulatory networks using biological replicates of a steady state dataset. Genes are first clustered, if applicable, and then networks are inferred for each cluster. The output is a text file that can be uploaded into Cytoscape for visualization.'};
            app.RTP_ClusteringOptionsPanel = uipanel(app.RTPSTARTab);
            app.RTP_ClusteringOptionsPanel.Title = 'Clustering Options';
            app.ClusteringSeedLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.ClusteringSeedLabel.Text = 'Clustering Seed';
            app.RTP_ClusteringSeedEditField = uieditfield(app.RTP_ClusteringOptionsPanel, 'numeric');
            app.RTP_ClusteringSeedEditField.Limits = [0 Inf];
            app.RTP_ClusteringSeedEditField.ValueDisplayFormat = '%.0f';
            app.RTP_ClusteringSeedEditField.Enable = 'off';
            app.RTP_ConnectHubsLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.RTP_ConnectHubsLabel.Text = 'Connect Hubs';
            app.RTP_ConnectHubsCheckBox = uicheckbox(app.RTP_ClusteringOptionsPanel);
            app.RTP_ConnectHubsCheckBox.Enable = 'off';
            app.RTP_ConnectHubsCheckBox.Text = '';
            app.RTP_ConnectHubsCheckBox.Value = true;
            app.RTP_ClusteringTypeDropDown = uidropdown(app.RTP_ClusteringOptionsPanel);
            app.RTP_ClusteringTypeDropDown.Items = {'Spatial', 'Temporal'};
            app.RTP_ClusteringTypeDropDown.ItemsData = {'S', 'T'};
            app.RTP_ClusteringTypeDropDown.Enable = 'off';
            app.RTP_ClusteringTypeDropDown.Value = 'S';
            app.RTP_ClusteringTypeLabel = uilabel(app.RTP_ClusteringOptionsPanel);
            app.RTP_ClusteringTypeLabel.Text = 'Clustering Type';
            app.RTP_GeneralOptionsPanel = uipanel(app.RTPSTARTab);
            app.RTP_GeneralOptionsPanel.Title = 'General Options';
            app.NumberofIterationsLabel = uilabel(app.RTP_GeneralOptionsPanel);
            app.NumberofIterationsLabel.Text = 'Number of Iterations';
            app.RTP_NumitersEditField = uieditfield(app.RTP_GeneralOptionsPanel, 'numeric');
            app.RTP_NumitersEditField.Limits = [1 Inf];
            app.RTP_NumitersEditField.ValueDisplayFormat = '%.0f';
            app.RTP_NumitersEditField.Value = 1;
            app.EdgeProportionLabel = uilabel(app.RTP_GeneralOptionsPanel);
            app.EdgeProportionLabel.Text = 'Edge Proportion';
            app.RTP_MaxpropEditField = uieditfield(app.RTP_GeneralOptionsPanel, 'numeric');
            app.RTP_MaxpropEditField.Limits = [0 1];
            app.RTP_MaxpropEditField.Value = 0.33;
            app.RTP_InputFilesPanel = uipanel(app.RTPSTARTab);
            app.RTP_InputFilesPanel.Title = 'Input Files';
            app.ClusteringFileOptionalLabel = uilabel(app.RTP_InputFilesPanel);
            app.ClusteringFileOptionalLabel.Text = 'Clustering File (Optional)';
            app.RTP_ClusteringFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.RTP_ClusteringFileEditField.ValueChangedFcn = createCallbackFcn(app, @RTP_ClusteringFileEditFieldValueChanged, true);
            app.OutputsFolderLabel = uilabel(app.RTP_InputFilesPanel);
            app.OutputsFolderLabel.Text = 'Outputs Folder';
            app.RTP_OutputsFolderEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.GenesFileLabel = uilabel(app.RTP_InputFilesPanel);
            app.GenesFileLabel.Text = 'Genes File';
            app.RTP_GeneFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.GeneExpressionFileEditFieldLabel = uilabel(app.RTP_InputFilesPanel);
            app.GeneExpressionFileEditFieldLabel.Text = 'Gene Expression File';
            app.RTP_GeneExpressionFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.TimeCourseFileOptionalLabel = uilabel(app.RTP_InputFilesPanel);
            app.TimeCourseFileOptionalLabel.Text = 'Time Course File (Optional)';
            app.RTP_TimeCourseFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.ResultsFileNameEditFieldLabel = uilabel(app.RTP_InputFilesPanel);
            app.ResultsFileNameEditFieldLabel.Text = 'Results File Name';
            app.RTP_ResultsFileNameEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.RTP_ResultsFileNameEditField.Value = 'biograph.txt';
            app.GeneNameFileOptionalLabel = uilabel(app.RTP_InputFilesPanel);
            app.GeneNameFileOptionalLabel.Text = 'Gene Name File (Optional)';
            app.RTP_GeneSymbolsFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.RTP_GeneSymbolsFileEditField.Value = 'GeneNames.xlsx';
            app.TFFileOptionalLabel = uilabel(app.RTP_InputFilesPanel);
            app.TFFileOptionalLabel.Text = 'TF File (Optional)';
            app.RTP_TFFileEditField = uieditfield(app.RTP_InputFilesPanel, 'text');
            app.RTP_TFFileEditField.Value = 'Arabidopsis_TFs.xlsx';
            app.RTP_InputsInfoPanel = uipanel(app.RTPSTARTab);
            app.RTP_InputsInfoPanel.Title = 'Inputs Info';
            app.RTP_InfoListBox = uilistbox(app.RTP_InputsInfoPanel);
            app.RTP_InfoListBox.Items = {'Gene File', 'Gene Expression File', 'Gene Name File', 'TF File', 'Clustering File', 'Time Course File', 'Results File Name', 'Connect Hubs', 'Clustering Seed', 'Clustering Type', 'Number of Iterations', 'Edge Proportion', 'Outputs Folder'};
            app.RTP_InfoListBox.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.RTP_InfoListBox.ValueChangedFcn = createCallbackFcn(app, @RTP_InfoListBoxValueChanged, true);
            app.RTP_InfoListBox.Value = '1';
            app.RTP_InputsInfoTextArea = uitextarea(app.RTP_InputsInfoPanel);
            app.RTP_InputsInfoTextArea.Editable = 'off';
            app.RTP_InputsInfoTextArea.Value = {'Gene Expression File must be an Excel file containing the expression data being used for GRN inference.'};
            app.RTP_RunPanel = uipanel(app.RTPSTARTab);
            app.RTP_RunButton = uibutton(app.RTP_RunPanel, 'push');
            app.RTP_RunButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_RunButtonPushed, true);
            app.RTP_RunButton.Text = 'Run';
            app.RTP_StatusField = uitextarea(app.RTP_RunPanel);
            app.RTP_StatusField.Editable = 'off';
            app.RTP_OutputsFolderButton = uibutton(app.RTPSTARTab, 'push');
            app.RTP_OutputsFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_OutputsFolderButtonPushed, true);
            app.RTP_OutputsFolderButton.Text = 'Choose Folder';
            app.RTP_GeneFileButton = uibutton(app.RTPSTARTab, 'push');
            app.RTP_GeneFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_GeneFileButtonPushed, true);
            app.RTP_GeneFileButton.Text = 'Choose File';
            app.RTP_GeneExpressionFileButton = uibutton(app.RTPSTARTab, 'push');
            app.RTP_GeneExpressionFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_GeneExpressionFileButtonButtonPushed, true);
            app.RTP_GeneExpressionFileButton.Text = 'Choose File';
            app.RTP_GeneSymbolsFileButton = uibutton(app.RTPSTARTab, 'push');
            app.RTP_GeneSymbolsFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_GeneSymbolsFileButtonButtonPushed, true);
            app.RTP_GeneSymbolsFileButton.Text = 'Choose File';
            app.RTP_TFFileButton = uibutton(app.RTPSTARTab, 'push');
            app.RTP_TFFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_TFFileButtonPushed, true);
            app.RTP_TFFileButton.Text = 'Choose File';
            app.RTP_ClusteringFileButton = uibutton(app.RTPSTARTab, 'push');
            app.RTP_ClusteringFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_ClusteringFileButtonButtonPushed, true);
            app.RTP_ClusteringFileButton.Text = 'Choose File';
            app.RTP_TimeCourseFileButton = uibutton(app.RTPSTARTab, 'push');
            app.RTP_TimeCourseFileButton.ButtonPushedFcn = createCallbackFcn(app, @RTP_TimeCourseFileButtonPushed, true);
            app.RTP_TimeCourseFileButton.Text = 'Choose File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TuxNet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end